classdef hannah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        LoadGeometryMenu           matlab.ui.container.Menu
        Panel_4                    matlab.ui.container.Panel
        Panel_2                    matlab.ui.container.Panel
        Panel_3                    matlab.ui.container.Panel
        Panel                      matlab.ui.container.Panel
        SelectRegionDropDownLabel  matlab.ui.control.Label
        SelectRegionDropDown       matlab.ui.control.DropDown
        RegionLabel                matlab.ui.control.Label
        R1_Im                      matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        R1_Re                      matlab.ui.control.NumericEditField
        RealLabel                  matlab.ui.control.Label
        ImaginaryLabel             matlab.ui.control.Label
        KnobDirection              matlab.ui.control.Knob
        WavenumberSlider           matlab.ui.control.Slider
        IncidentDirectionLabel     matlab.ui.control.Label
        WavenumberLabel            matlab.ui.control.Label
        IndicesofRefractionLabel   matlab.ui.control.Label
        PlotSettingsLabel          matlab.ui.control.Label
        btn_plt_type               matlab.ui.container.ButtonGroup
        btn_geom                   matlab.ui.control.RadioButton
        btn_inc                    matlab.ui.control.RadioButton
        btn_sca                    matlab.ui.control.RadioButton
        btn_tot                    matlab.ui.control.RadioButton
        btn_plt_app                matlab.ui.container.ButtonGroup
        btn_real                   matlab.ui.control.RadioButton
        btn_ima                    matlab.ui.control.RadioButton
        btn_abs                    matlab.ui.control.RadioButton
        SolveButton                matlab.ui.control.Button
        HaltButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nregions  = 5;
        direction = 0;
        wavenumber= 1;
        region_params = [1,1,1,1,1;0,0,0,0,0];
        rcurr = 1;
        chnk_array; 
        clmparams = [];
        hs = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: KnobDirection
        function KnobDirectionValueChanged(app, event)
            value = app.KnobDirection.Value;
            dir = (value - 50)*30/20
            app.direction = dir;
            if (dir <-30)
                app.KnobDirection.Value = 30;
                app.direction = -30;
            end
            if (dir >30)
                app.KnobDirection.Value = 70;
                app.direction = 30;
            end
        end

        % Value changed function: WavenumberSlider
        function WavenumberSliderValueChanged(app, event)
            value = app.WavenumberSlider.Value;
            if (value == 0)
                app.WavenumberSlider.Value = 0.01;
            end
            value = app.WavenumberSlider.Value;
            value
            app.wavenumber = value;
        end

        % Value changed function: SelectRegionDropDown
        function SelectRegionDropDownValueChanged(app, event)
            value = app.SelectRegionDropDown.Value;
            app.RegionLabel.Text = value;
            str_lab = value;
            str_lab = erase(str_lab,"Region #");
            rnum = str2num(str_lab);
            app.R1_Re.Value = app.region_params(1,rnum);
            app.R1_Im.Value = app.region_params(2,rnum);
            app.rcurr = rnum;
            app.btn_plt_type.SelectedObject = app.btn_geom;
            app.btn_plt_app.SelectedObject = app.btn_real;
        end

        % Value changed function: R1_Re
        function R1_ReValueChanged(app, event)
            value = app.R1_Re.Value;
            app.region_params(1,app.rcurr)=value;
        end

        % Value changed function: R1_Im
        function R1_ImValueChanged(app, event)
            value = app.R1_Im.Value;
            if (value <0)
                value = 0;
                app.R1_Im.Value = value;
            end
            app.region_params(2,app.rcurr)=value;
        end

        % Selection changed function: btn_plt_type
        function btn_plt_typeSelectionChanged(app, event)
            selectedButton = app.btn_plt_type.SelectedObject;
            
        end

        % Selection changed function: btn_plt_app
        function btn_plt_appSelectionChanged(app, event)
            selectedButton = app.btn_plt_app.SelectedObject;
            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
        end

        % Button pushed function: HaltButton
        function HaltButtonPushed(app, event)
            
        end

        % Menu selected function: LoadGeometryMenu
        function LoadGeometryMenuSelected(app, event)
            axis_plt = app.UIAxes;
            cla(axis_plt);
            app.chnk_array = [];
            [hs,chnk_arr,params] = load_geom(app.UIAxes);
            app.chnk_array = chnk_arr;
            app.clmparams = params;
            app.hs = hs;
            hs
            params
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadGeometryMenu = uimenu(app.Menu);
            app.LoadGeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadGeometryMenuSelected, true);
            app.LoadGeometryMenu.Text = 'Load Geometry';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.Panel_2);
            app.Panel = uipanel(app.UIFigure);
            app.SelectRegionDropDownLabel = uilabel(app.Panel);
            app.SelectRegionDropDownLabel.Text = 'Select Region';
            app.SelectRegionDropDown = uidropdown(app.Panel);
            app.SelectRegionDropDown.Items = {'Region #1', 'Region #2', 'Region #3', 'Region #4'};
            app.SelectRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRegionDropDownValueChanged, true);
            app.SelectRegionDropDown.Value = 'Region #1';
            app.RegionLabel = uilabel(app.Panel);
            app.RegionLabel.Text = 'Region #1';
            app.R1_Im = uieditfield(app.Panel, 'numeric');
            app.R1_Im.ValueChangedFcn = createCallbackFcn(app, @R1_ImValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.R1_Re = uieditfield(app.UIFigure, 'numeric');
            app.R1_Re.ValueChangedFcn = createCallbackFcn(app, @R1_ReValueChanged, true);
            app.R1_Re.Value = 1;
            app.RealLabel = uilabel(app.UIFigure);
            app.RealLabel.Text = 'Real';
            app.ImaginaryLabel = uilabel(app.UIFigure);
            app.ImaginaryLabel.Text = 'Imaginary';
            app.KnobDirection = uiknob(app.UIFigure, 'continuous');
            app.KnobDirection.MajorTicks = [30 40 50 60 70];
            app.KnobDirection.MajorTickLabels = {'-30', '-15', '0', '15', '30'};
            app.KnobDirection.ValueChangedFcn = createCallbackFcn(app, @KnobDirectionValueChanged, true);
            app.KnobDirection.MinorTicks = [30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70];
            app.KnobDirection.Value = 50;
            app.WavenumberSlider = uislider(app.UIFigure);
            app.WavenumberSlider.Limits = [0 80];
            app.WavenumberSlider.MajorTicks = [0 20 40 60 80];
            app.WavenumberSlider.MajorTickLabels = {'0', '20', '40', '60', '80'};
            app.WavenumberSlider.ValueChangedFcn = createCallbackFcn(app, @WavenumberSliderValueChanged, true);
            app.WavenumberSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80];
            app.WavenumberSlider.Value = 1;
            app.IncidentDirectionLabel = uilabel(app.UIFigure);
            app.IncidentDirectionLabel.Text = 'Incident Direction';
            app.WavenumberLabel = uilabel(app.UIFigure);
            app.WavenumberLabel.Text = 'Wavenumber';
            app.IndicesofRefractionLabel = uilabel(app.UIFigure);
            app.IndicesofRefractionLabel.Text = 'Indices of Refraction';
            app.PlotSettingsLabel = uilabel(app.UIFigure);
            app.PlotSettingsLabel.Text = 'Plot Settings:';
            app.btn_plt_type = uibuttongroup(app.UIFigure);
            app.btn_plt_type.SelectionChangedFcn = createCallbackFcn(app, @btn_plt_typeSelectionChanged, true);
            app.btn_geom = uiradiobutton(app.btn_plt_type);
            app.btn_geom.Text = 'Geometry';
            app.btn_geom.Value = true;
            app.btn_inc = uiradiobutton(app.btn_plt_type);
            app.btn_inc.Text = 'Incident Field';
            app.btn_sca = uiradiobutton(app.btn_plt_type);
            app.btn_sca.Text = 'Scattered Field';
            app.btn_tot = uiradiobutton(app.btn_plt_type);
            app.btn_tot.Text = 'Total Field';
            app.btn_plt_app = uibuttongroup(app.UIFigure);
            app.btn_plt_app.SelectionChangedFcn = createCallbackFcn(app, @btn_plt_appSelectionChanged, true);
            app.btn_real = uiradiobutton(app.btn_plt_app);
            app.btn_real.Text = 'Real Part';
            app.btn_real.Value = true;
            app.btn_ima = uiradiobutton(app.btn_plt_app);
            app.btn_ima.Text = 'Imaginary Part';
            app.btn_abs = uiradiobutton(app.btn_plt_app);
            app.btn_abs.Text = 'Absolute Value';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.HaltButton = uibutton(app.UIFigure, 'push');
            app.HaltButton.ButtonPushedFcn = createCallbackFcn(app, @HaltButtonPushed, true);
            app.HaltButton.Text = 'Halt';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hannah

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end